classdef Design_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PVSystemDesignUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StandAloneTab                   matlab.ui.container.Tab
        LocationDropDownLabel           matlab.ui.control.Label
        SA_LocationDropDown             matlab.ui.control.DropDown
        SA_ConsumerLoadDataPanel        matlab.ui.container.Panel
        LoadNoLabel                     matlab.ui.control.Label
        L1W_EditField                   matlab.ui.control.NumericEditField
        L2W_EditField                   matlab.ui.control.NumericEditField
        L3W_EditField                   matlab.ui.control.NumericEditField
        L4W_EditField                   matlab.ui.control.NumericEditField
        L5W_EditField                   matlab.ui.control.NumericEditField
        WattRatingLabel                 matlab.ui.control.Label
        L1H_EditField                   matlab.ui.control.NumericEditField
        L2H_EditField                   matlab.ui.control.NumericEditField
        L3H_EditField                   matlab.ui.control.NumericEditField
        L4H_EditField                   matlab.ui.control.NumericEditField
        L5H_EditField                   matlab.ui.control.NumericEditField
        HoursofUseLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        SA_ConsumptionPatternPanel      matlab.ui.container.Panel
        HoursLabel                      matlab.ui.control.Label
        CP1_EditField                   matlab.ui.control.NumericEditField
        CP2_EditField                   matlab.ui.control.NumericEditField
        CP3_EditField                   matlab.ui.control.NumericEditField
        CP6_EditField                   matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        AverageConnectedLoadinWattsLabel  matlab.ui.control.Label
        CP4_EditField                   matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        CP5_EditField                   matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        AverageConnectedLoadinWattsLabel_2  matlab.ui.control.Label
        HoursLabel_2                    matlab.ui.control.Label
        DaysofAutonomyofthebatterystoragesystemDropDownLabel  matlab.ui.control.Label
        DaysofAutonomyofthebatterystoragesystemDropDown  matlab.ui.control.DropDown
        TypeofPVModuleDropDownLabel     matlab.ui.control.Label
        SA_TypeofPVModuleDropDown       matlab.ui.control.DropDown
        SA_ResultsPanel                 matlab.ui.container.Panel
        BatteryAhEditFieldLabel         matlab.ui.control.Label
        BatteryAhEditField              matlab.ui.control.NumericEditField
        BatteryVoltageEditFieldLabel    matlab.ui.control.Label
        BatteryVoltageEditField         matlab.ui.control.NumericEditField
        PVkWRatingEditFieldLabel        matlab.ui.control.Label
        PVkWRatingEditField             matlab.ui.control.NumericEditField
        TotalInvestmentEditFieldLabel   matlab.ui.control.Label
        SA_TotalInvestmentEditField     matlab.ui.control.NumericEditField
        SA_UIAxes                       matlab.ui.control.UIAxes
        ModuleCostEditFieldLabel        matlab.ui.control.Label
        SA_ModuleCostEditField          matlab.ui.control.NumericEditField
        BatteryCostEditFieldLabel       matlab.ui.control.Label
        SA_BatteryCostEditField         matlab.ui.control.NumericEditField
        EPCCostEditFieldLabel           matlab.ui.control.Label
        SA_EPCCostEditField             matlab.ui.control.NumericEditField
        TECCostEditFieldLabel           matlab.ui.control.Label
        SA_TECCostEditField             matlab.ui.control.NumericEditField
        AMCEditFieldLabel               matlab.ui.control.Label
        SA_AMCEditField                 matlab.ui.control.NumericEditField
        LossofLoadEditFieldLabel        matlab.ui.control.Label
        SA_LossofLoadEditField          matlab.ui.control.NumericEditField
        NoteLabel_2                     matlab.ui.control.Label
        NoteLabel_3                     matlab.ui.control.Label
        EconomicGrossEvaluationallfiguresinINRLabel  matlab.ui.control.Label
        SA_Error_Label                  matlab.ui.control.Label
        AreainsqmeterLabel              matlab.ui.control.Label
        SA_AreaEditField                matlab.ui.control.NumericEditField
        SA_CalculateResultsButton       matlab.ui.control.Button
        NoteLabel_8                     matlab.ui.control.Label
        MonocrystallineButton           matlab.ui.control.Button
        PolycrystallineButton           matlab.ui.control.Button
        ThinFilmButton                  matlab.ui.control.Button
        GridTieTab                      matlab.ui.container.Tab
        DesignCriteriaButtonGroup       matlab.ui.container.ButtonGroup
        GT_AreainsqmButton              matlab.ui.control.RadioButton
        GT_KWpratingButton              matlab.ui.control.RadioButton
        GT_IP_EditField                 matlab.ui.control.NumericEditField
        UtilitySanctionedLoadinkWEditFieldLabel  matlab.ui.control.Label
        UtilitySanctionedLoadinkWEditField  matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        GT_UIAxes_1                     matlab.ui.control.UIAxes
        GT_UIAxes_2                     matlab.ui.control.UIAxes
        GT_EconomicGrossEvaluationPanel  matlab.ui.container.Panel
        NoteLabel_4                     matlab.ui.control.Label
        TotalInvestmentEditField_2Label  matlab.ui.control.Label
        GT_TotalInvestmentEditField     matlab.ui.control.NumericEditField
        ModuleCostEditField_2Label      matlab.ui.control.Label
        GT_ModuleCostEditField          matlab.ui.control.NumericEditField
        EPCCostEditField_2Label         matlab.ui.control.Label
        GT_EPCCostEditField             matlab.ui.control.NumericEditField
        TECCostEditField_2Label         matlab.ui.control.Label
        GT_TECCostEditField             matlab.ui.control.NumericEditField
        AMCEditField_2Label             matlab.ui.control.Label
        GT_AMCEditField                 matlab.ui.control.NumericEditField
        NoteLabel_5                     matlab.ui.control.Label
        GT_CalculateResultsButton       matlab.ui.control.Button
        LocationDropDown_2Label         matlab.ui.control.Label
        GT_LocationDropDown             matlab.ui.control.DropDown
        TypeofPVModuleDropDown_2Label   matlab.ui.control.Label
        GT_TypeofPVModuleDropDown       matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        InverterSpecsEditFieldLabel     matlab.ui.control.Label
        GT_InverterSpecsEditField       matlab.ui.control.EditField
        KwpRatingEditFieldLabel         matlab.ui.control.Label
        GT_KwpRatingEditField           matlab.ui.control.NumericEditField
        PowerPlantTab                   matlab.ui.container.Tab
        DesignCriteriaButtonGroup_2     matlab.ui.container.ButtonGroup
        PP_AreainsqmButton              matlab.ui.control.RadioButton
        PP_CostButton                   matlab.ui.control.RadioButton
        PP_IP_EditField                 matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        PP_UIAxes_1                     matlab.ui.control.UIAxes
        PP_UIAxes_2                     matlab.ui.control.UIAxes
        PP_EconomicGrossEvaluationPanel  matlab.ui.container.Panel
        NoteLabel_6                     matlab.ui.control.Label
        TotalInvestmentEditField_3Label  matlab.ui.control.Label
        PP_TotalInvestmentEditField     matlab.ui.control.NumericEditField
        ModuleCostEditField_3Label      matlab.ui.control.Label
        PP_ModuleCostEditField          matlab.ui.control.NumericEditField
        EPCCostEditField_3Label         matlab.ui.control.Label
        PP_EPCCostEditField             matlab.ui.control.NumericEditField
        TECCostEditField_3Label         matlab.ui.control.Label
        PP_TECCostEditField             matlab.ui.control.NumericEditField
        AMCEditField_3Label             matlab.ui.control.Label
        PP_AMCEditField                 matlab.ui.control.NumericEditField
        NoteLabel_7                     matlab.ui.control.Label
        EBChargesEditFieldLabel         matlab.ui.control.Label
        PP_EBChargesEditField           matlab.ui.control.NumericEditField
        PP_PlantConfigurationPanel      matlab.ui.container.Panel
        NumberofStringsEditFieldLabel   matlab.ui.control.Label
        PP_NumberofStringsEditField     matlab.ui.control.NumericEditField
        NumberofSubStringsEditFieldLabel  matlab.ui.control.Label
        PP_NumberofSubStringsEditField  matlab.ui.control.NumericEditField
        NumberofInvertersEditFieldLabel  matlab.ui.control.Label
        PP_NumberofInvertersEditField   matlab.ui.control.NumericEditField
        InverterSpecsEditField_2Label   matlab.ui.control.Label
        PP_InverterSpecsEditField       matlab.ui.control.NumericEditField
        MWLabel                         matlab.ui.control.Label
        PlantMWCapacityEditFieldLabel   matlab.ui.control.Label
        PP_PlantMWCapacityEditField     matlab.ui.control.NumericEditField
        PlantAreainsqmetersEditFieldLabel  matlab.ui.control.Label
        PlantAreainsqmetersEditField    matlab.ui.control.NumericEditField
        PP_CalculateResultsButton       matlab.ui.control.Button
        LocationDropDown_3Label         matlab.ui.control.Label
        PP_LocationDropDown             matlab.ui.control.DropDown
        TypeofPVModuleDropDown_3Label   matlab.ui.control.Label
        PP_TypeofPVModuleDropDown       matlab.ui.control.DropDown
        InverterConfigurationLabel      matlab.ui.control.Label
        PP_RedundancyDropDown           matlab.ui.control.DropDown
        Label2                          matlab.ui.control.Label
    end


    methods (Access = private)


    end


    methods (Access = private)

        % Button pushed function: SA_CalculateResultsButton
        function SA_CalculateResultsButtonPushed(app, event)
           
           
            L1W = app.L1W_EditField.Value;
            L2W = app.L2W_EditField.Value;
            L3W = app.L3W_EditField.Value;
            L4W = app.L4W_EditField.Value;
            L5W = app.L5W_EditField.Value;
            
            L1H = app.L1H_EditField.Value;
            L2H = app.L2H_EditField.Value;
            L3H = app.L3H_EditField.Value;
            L4H = app.L4H_EditField.Value;
            L5H = app.L5H_EditField.Value;
            
            CP1 = app.CP1_EditField.Value;
            CP2 = app.CP2_EditField.Value;
            CP3 = app.CP3_EditField.Value;
            CP4 = app.CP4_EditField.Value;
            CP5 = app.CP5_EditField.Value;
            CP6 = app.CP6_EditField.Value;
            
            CP = [CP1 CP2 CP3 CP4 CP5 CP6];
            TotDemand = (L1W*L1H + L2W*L2H + L3W*L3H + L4W*L4H + L5W*L5H) / 1000;
            TotDemandMat = [TotDemand ;TotDemand; TotDemand; TotDemand; TotDemand; TotDemand; TotDemand; TotDemand; TotDemand ;TotDemand; TotDemand; TotDemand];
            
            %Error Check
            CPTot = (CP1+CP2+CP3+CP4+CP5+CP6)*4/1000;
            
            if (CPTot == TotDemand)
               
            SunHours = 4;
            DaysofAutonomy = app.DaysofAutonomyofthebatterystoragesystemDropDown.Value;
            
            KWPeak = TotDemand/SunHours + mean(CP)*DaysofAutonomy/(1000*20);
            app.PVkWRatingEditField.Value = KWPeak;
         
            % Area in sq meters
            Area = 6*KWPeak;
            app.SA_AreaEditField.Value = Area;
            %Battery KWh Calculation
            
            KWhBat = KWPeak*SunHours - (CP4*4/1000);
            
            %Battery Voltage Calculation
           
            MaxLoad = max(CP);
            if(MaxLoad/12 > 40)
                if(MaxLoad/24 > 40)
                    if(MaxLoad/48 > 40)
                        BatVolt = 96;
                    else
                        BatVolt = 48;
                    end
                else
                    BatVolt = 24;
                end 
            else
                BatVolt = 12;
            end
            app.BatteryVoltageEditField.Value = BatVolt;
           
            % Battery Ah (divide by 0.9 as we are using 90% dept of charge).
            BatAh = KWhBat*1000/BatVolt/0.9;
            app.BatteryAhEditField.Value = BatAh;
            
            %Economic Gross Evaluation
                %Irradiance Vectors
                blr = [4.81;5.87;6.33;6.47;6.22;5.65;5.2;5.24;5.35;4.43;3.84;3.98];
                del = [3.95;4.91;6.13;6.87;7.18;6.52;5.3;5.17;5.73;5.25;4.27;3.57];
            
            ModuleCost = 0;
            eff = 0;
            PVModuleType = app.SA_TypeofPVModuleDropDown.Value;
            switch PVModuleType
                case "Monocrystalline"
                    ModuleCost = 30;
                   eff = 0.14;
                case "Polycrystalline"
                    ModuleCost = 25;
                   eff = 0.11;
                case "Thin Film"
                    ModuleCost = 20;
                   eff = 0.08;
            end
                blr1 = [blr*Area*eff TotDemandMat];
                del1 = [del*Area*eff TotDemandMat];
                
            EPC = 0.65*ModuleCost*KWPeak*1000;
            TEC = 0.6*ModuleCost*KWPeak*1000;
            BatCost = 6.7*KWhBat*1000;
            AMC = 0.1*ModuleCost*KWPeak*1000;
            TotCost = EPC + TEC + BatCost + AMC + 2.5*ModuleCost*KWPeak*1000;
            app.SA_ModuleCostEditField.Value = 2.5*ModuleCost*KWPeak*1000;
            app.SA_EPCCostEditField.Value = EPC;
            app.SA_TECCostEditField.Value = TEC;
            app.SA_AMCEditField.Value = AMC;
            app.SA_BatteryCostEditField.Value = BatCost;
            app.SA_TotalInvestmentEditField.Value = TotCost;
            
            LoLV =0;
            j= 0;
                
            City = app.SA_LocationDropDown.Value;
            switch City
                case "Bangalore"  
                bar(app.SA_UIAxes, blr1);
                legend(app.SA_UIAxes, 'PV Generation','Load Demand')
                %Loss of Load Calculation for blr
                LoLVector = ((blr1(:,2) - blr1(:,1))./blr1(:,2))*100;
                for i =1 :12
                    if LoLVector(i) > 0
                    LoLV  = LoLV+LoLVector(i);
                    j= j+1;
                    end
                end
                    if(j>0)
                     app.SA_LossofLoadEditField.Value = (LoLV/j);
                    else
                     app.SA_LossofLoadEditField.Value = 0;
                    end
                case "New Delhi"
                bar(app.SA_UIAxes, del1);
                legend(app.SA_UIAxes, 'PV Generation','Load Demand')
                %Loss of Load Calculation for del
                LoLVector = ((del1(:,2) - del1(:,1))./del1(:,2))*100;
                for i =1 :12
                    if LoLVector(i) > 0
                    LoLV  = LoLV+LoLVector(i);
                    j= j+1;
                    end
                end
                if(j>0)
                     app.SA_LossofLoadEditField.Value = (LoLV/j);
                    else
                     app.SA_LossofLoadEditField.Value = 0;
                end
            end
            end
            if(CPTot ~= TotDemand)
                app.SA_Error_Label.Text = "Error in Load Data";
                app.BatteryAhEditField.Value = 0;
                app.BatteryVoltageEditField.Value = 0;
                app.PVkWRatingEditField.Value = 0;
                app.SA_TotalInvestmentEditField.Value = 0;
                app.SA_ModuleCostEditField.Value = 0;
                app.SA_BatteryCostEditField.Value = 0;
                app.SA_EPCCostEditField.Value = 0;
                app.SA_TECCostEditField.Value = 0;
                app.SA_TECCostEditField.Value = 0;
                app.SA_LossofLoadEditField.Value = 0;
                app.SA_AMCEditField.Value = 0;
                bar(app.SA_UIAxes, 0);
                app.SA_AreaEditField.Value = 0;
            end
                
        end

        % Button pushed function: GT_CalculateResultsButton
        function GT_CalculateResultsButtonPushed(app, event)
            SanctLoad = app.UtilitySanctionedLoadinkWEditField.Value;
            
            if(SanctLoad <= 5)
                app.GT_InverterSpecsEditField.Value = "Single Phase, 50Hz, 230Vrms";
            end
            if(SanctLoad > 5 && SanctLoad < 17)
                 app.GT_InverterSpecsEditField.Value = "Three Phase, 50Hz, 415Vrms";
            end
            if(SanctLoad >= 17)
                app.GT_InverterSpecsEditField.Value = "Three Phase, 50Hz, 415Vrms with 3x5 A CT";
            end
            if( app.GT_AreainsqmButton.Value == 1)
                Area = app.GT_IP_EditField.Value;
                KWPeak = Area/6;
            end
            if ( app.GT_KWpratingButton.Value == 1)
                KWPeak = app.GT_IP_EditField.Value;
                Area = KWPeak*6;
            end
            
            %Economic Gross Evaluation
                %Irradiance Vectors
                blr = [4.81;5.87;6.33;6.47;6.22;5.65;5.2;5.24;5.35;4.43;3.84;3.98];
                del = [3.95;4.91;6.13;6.87;7.18;6.52;5.3;5.17;5.73;5.25;4.27;3.57];
            
            ModuleCost = 0;
            eff = 0;
            PVModuleType = app.GT_TypeofPVModuleDropDown.Value;
            switch PVModuleType
                case "Monocrystalline"
                    ModuleCost = 30;
                   eff = 0.14;
                case "Polycrystalline"
                    ModuleCost = 25;
                   eff = 0.11;
                case "Thin Film"
                    ModuleCost = 20;
                   eff = 0.08;
            end
                blr1 = blr.*(Area*eff);
                del1 = del.*(Area*eff);
                
            EPC = 0.65*ModuleCost*KWPeak*1000;
            TEC = 0.60*ModuleCost*KWPeak*1000;
            AMC = 0.1*ModuleCost*KWPeak*1000;
            TotCost = EPC + TEC + AMC + 2.5*ModuleCost*KWPeak*1000;
            app.GT_ModuleCostEditField.Value = 2.5*ModuleCost*KWPeak*1000;
            app.GT_EPCCostEditField.Value = EPC;
            app.GT_TECCostEditField.Value = TEC;
            app.GT_AMCEditField.Value = AMC;
            app.GT_TotalInvestmentEditField.Value = TotCost;    
            app.GT_KwpRatingEditField.Value = KWPeak;    
                
            City = app.GT_LocationDropDown.Value;
            switch City
                case "Bangalore"  
                    bar(app.GT_UIAxes_2, blr1,'g');
                    legend(app.GT_UIAxes_2, 'PV Generation');
                    bar(app.GT_UIAxes_1, blr);
                    legend(app.GT_UIAxes_1, 'Irradiance');
                case "New Delhi"
                bar(app.GT_UIAxes_2, del1,'g');
                legend(app.GT_UIAxes_2, 'PV Generation');
                bar(app.GT_UIAxes_1, del);
                legend(app.GT_UIAxes_1, 'Irradiance');
            end
            
                
        end

        % Button pushed function: PP_CalculateResultsButton
        function PP_CalculateResultsButtonPushed(app, event)
            %Module Type selection
            ModuleCost = 0;
            EPC = 0;
            AMC = 0;
            EBC = 0;
            TEC = 0;
            TotCost = 0;
            TotModuleCost = 0;
            TotCost1 = 0;
            TotCost2 = 0;
            TotCost3 = 0;
            TotCost0 = 0;
            eff = 0;
            PVModuleType = app.PP_TypeofPVModuleDropDown.Value;
            switch PVModuleType
                case "Monocrystalline"
                    ModuleCost = 30;
                   eff = 0.14;
                case "Polycrystalline"
                    ModuleCost = 25;
                   eff = 0.11;
                case "Thin Film"
                    ModuleCost = 20;
                   eff = 0.08;
            end
            RedundancyLevel = 0;
            NumberofInverters = 0;
            MF=1;
            Redundancy = app.PP_RedundancyDropDown.Value;
            switch Redundancy
                case "Level 0 - Centralised Inverter"
                    RedundancyLevel = 1;
                    NumberofInverters = 1;
                    MF = 1*0.66;
                case "Level 1 - String Inverter"
                    RedundancyLevel = 1.2;
                    NumberofInverters = 2;
                    MF = 1.08*0.66;
                case "Level 2 - String Inverter"
                    RedundancyLevel = 1.3;
                    NumberofInverters = 5;
                    MF = 1.14*0.66;
                case "Level 3 - String Inverter"
                    RedundancyLevel = 1.4;
                    NumberofInverters = 10;
                    MF = 1.20*0.66;
            end
            app.PP_NumberofInvertersEditField.Value = NumberofInverters;
            
            
            %Design Criteria
            if( app.PP_AreainsqmButton.Value == 1)
                Area = app.PP_IP_EditField.Value;
                MWPeak = Area/(8*1000);
                
                
                TotModuleCost = ModuleCost*MWPeak*1000000*0.66;
                EPC = 0.57*ModuleCost*MWPeak*1000000*RedundancyLevel*MF;
                TEC = 0.25*ModuleCost*MWPeak*1000000*MF;
                EBC = 0.425*ModuleCost*MWPeak*1000000*MF;
                AMC = 0.25*ModuleCost*MWPeak*1000000*MF + 0.06*EPC;
                TotCost = TotModuleCost+ EPC + TEC + EBC + AMC;
                
            end
            if ( app.PP_CostButton.Value == 1)
                TotCost = app.PP_IP_EditField.Value;
                EPC = 0.23*TotCost*MF;
                AMC = 0.1*TotCost*MF;
                TEC = 0.1*TotCost*MF;
                EBC = 0.17*TotCost*MF;
                TotModuleCost = TotCost -(EPC+AMC+EBC+TEC);
                MWPeak = TotModuleCost/(ModuleCost*1000000);
                Area = MWPeak*1000*8;
                
            end
            
            InvMWRating = MWPeak/NumberofInverters;
            NoofStrings = MWPeak/0.1;
            app.PP_NumberofStringsEditField.Value = NoofStrings;
            app.PP_NumberofSubStringsEditField.Value = 10;
            
             %Economic Gross Evaluation
                
                
            app.PP_PlantMWCapacityEditField.Value = MWPeak;
            app.PP_ModuleCostEditField.Value = TotModuleCost;
            app.PP_EPCCostEditField.Value = EPC;
            app.PP_TECCostEditField.Value = TEC;
            app.PP_AMCEditField.Value = AMC;
            app.PP_EBChargesEditField.Value = EBC;
            app.PP_TotalInvestmentEditField.Value = TotCost;    
            app.PP_InverterSpecsEditField.Value = InvMWRating;
            app.PlantAreainsqmetersEditField.Value = Area;
            
            
            %Irradiance Vectors
                blr = [4.81;5.87;6.33;6.47;6.22;5.65;5.2;5.24;5.35;4.43;3.84;3.98];
                del = [3.95;4.91;6.13;6.87;7.18;6.52;5.3;5.17;5.73;5.25;4.27;3.57];
            
           
                blr1 = blr.*(Area*eff);
                del1 = del.*(Area*eff);
                
            %Redundancy vs cost 


            City = app.PP_LocationDropDown.Value;
            switch City
                case "Bangalore"  
                    bar(app.PP_UIAxes_2, blr1,'g');
                    legend(app.PP_UIAxes_2, 'PV Generation');
                    bar(app.PP_UIAxes_1, blr);
                    legend(app.PP_UIAxes_1, 'Irradiance');
                case "New Delhi"
                    bar(app.PP_UIAxes_2, del1,'g');
                    legend(app.PP_UIAxes_2, 'PV Generation');
                    bar(app.PP_UIAxes_1, del);
                    legend(app.PP_UIAxes_1, 'Irradiance');
            end
       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PVSystemDesignUIFigure = uifigure;
            app.PVSystemDesignUIFigure.Name = 'PV System Design';
            app.TabGroup = uitabgroup(app.PVSystemDesignUIFigure);
            app.StandAloneTab = uitab(app.TabGroup);
            app.StandAloneTab.Title = 'Stand Alone';
            app.LocationDropDownLabel = uilabel(app.StandAloneTab);
            app.LocationDropDownLabel.Text = 'Location';
            app.SA_LocationDropDown = uidropdown(app.StandAloneTab);
            app.SA_LocationDropDown.Items = {'New Delhi', 'Bangalore'};
            app.SA_LocationDropDown.Value = 'Bangalore';
            app.SA_ConsumerLoadDataPanel = uipanel(app.StandAloneTab);
            app.SA_ConsumerLoadDataPanel.Title = 'Consumer Load Data';
            app.LoadNoLabel = uilabel(app.SA_ConsumerLoadDataPanel);
            app.LoadNoLabel.Text = 'Load No';
            app.L1W_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L1W_EditField.Limits = [0 80];
            app.L1W_EditField.Value = 75;
            app.L2W_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L2W_EditField.Limits = [0 Inf];
            app.L2W_EditField.Value = 195;
            app.L3W_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L3W_EditField.Limits = [0 Inf];
            app.L3W_EditField.Value = 5150;
            app.L4W_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L4W_EditField.Limits = [0 Inf];
            app.L4W_EditField.Value = 80;
            app.L5W_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L5W_EditField.Limits = [0 Inf];
            app.WattRatingLabel = uilabel(app.SA_ConsumerLoadDataPanel);
            app.WattRatingLabel.Text = 'Watt Rating';
            app.L1H_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L1H_EditField.Limits = [0 Inf];
            app.L1H_EditField.Value = 12;
            app.L2H_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L2H_EditField.Limits = [0 Inf];
            app.L2H_EditField.Value = 6;
            app.L3H_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L3H_EditField.Limits = [0 Inf];
            app.L3H_EditField.Value = 1;
            app.L4H_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L4H_EditField.Limits = [0 Inf];
            app.L4H_EditField.Value = 24;
            app.L5H_EditField = uieditfield(app.SA_ConsumerLoadDataPanel, 'numeric');
            app.L5H_EditField.Limits = [0 Inf];
            app.HoursofUseLabel = uilabel(app.SA_ConsumerLoadDataPanel);
            app.HoursofUseLabel.Text = 'Hours of Use';
            app.Label = uilabel(app.SA_ConsumerLoadDataPanel);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.SA_ConsumerLoadDataPanel);
            app.Label_2.Text = '2.';
            app.Label_3 = uilabel(app.SA_ConsumerLoadDataPanel);
            app.Label_3.Text = '3.';
            app.Label_4 = uilabel(app.SA_ConsumerLoadDataPanel);
            app.Label_4.Text = '4.';
            app.Label_5 = uilabel(app.SA_ConsumerLoadDataPanel);
            app.Label_5.Text = '5.';
            app.SA_ConsumptionPatternPanel = uipanel(app.StandAloneTab);
            app.SA_ConsumptionPatternPanel.Title = 'Consumption Pattern';
            app.HoursLabel = uilabel(app.SA_ConsumptionPatternPanel);
            app.HoursLabel.Text = 'Hours';
            app.CP1_EditField = uieditfield(app.SA_ConsumptionPatternPanel, 'numeric');
            app.CP1_EditField.Limits = [0 Inf];
            app.CP1_EditField.ValueDisplayFormat = '%.1f';
            app.CP1_EditField.Value = 155;
            app.CP2_EditField = uieditfield(app.SA_ConsumptionPatternPanel, 'numeric');
            app.CP2_EditField.Limits = [0 Inf];
            app.CP2_EditField.ValueDisplayFormat = '%.1f';
            app.CP2_EditField.Value = 1442.5;
            app.CP3_EditField = uieditfield(app.SA_ConsumptionPatternPanel, 'numeric');
            app.CP3_EditField.Limits = [0 Inf];
            app.CP3_EditField.ValueDisplayFormat = '%.1f';
            app.CP3_EditField.Value = 80;
            app.CP6_EditField = uieditfield(app.SA_ConsumptionPatternPanel, 'numeric');
            app.CP6_EditField.Limits = [0 Inf];
            app.CP6_EditField.ValueDisplayFormat = '%.1f';
            app.CP6_EditField.Value = 155;
            app.Label_6 = uilabel(app.SA_ConsumptionPatternPanel);
            app.Label_6.Text = '00:00-03:59';
            app.Label_7 = uilabel(app.SA_ConsumptionPatternPanel);
            app.Label_7.Text = '04:00-07:59';
            app.Label_8 = uilabel(app.SA_ConsumptionPatternPanel);
            app.Label_8.Text = '08:00-11:59';
            app.Label_9 = uilabel(app.SA_ConsumptionPatternPanel);
            app.Label_9.Text = '20:00-23:59';
            app.AverageConnectedLoadinWattsLabel = uilabel(app.SA_ConsumptionPatternPanel);
            app.AverageConnectedLoadinWattsLabel.Text = {'Average Connected Load '; '(in Watts)'};
            app.CP4_EditField = uieditfield(app.SA_ConsumptionPatternPanel, 'numeric');
            app.CP4_EditField.Limits = [0 Inf];
            app.CP4_EditField.ValueDisplayFormat = '%.1f';
            app.CP4_EditField.Value = 177.5;
            app.Label_10 = uilabel(app.SA_ConsumptionPatternPanel);
            app.Label_10.Text = '12:00-15:59';
            app.CP5_EditField = uieditfield(app.SA_ConsumptionPatternPanel, 'numeric');
            app.CP5_EditField.Limits = [0 Inf];
            app.CP5_EditField.ValueDisplayFormat = '%.1f';
            app.CP5_EditField.Value = 275;
            app.Label_11 = uilabel(app.SA_ConsumptionPatternPanel);
            app.Label_11.Text = '16:00-19:59';
            app.NoteLabel = uilabel(app.SA_ConsumptionPatternPanel);
            app.NoteLabel.Text = 'Note: Enter Average Watt Rating of Connected Load for the Specified Time slots';
            app.AverageConnectedLoadinWattsLabel_2 = uilabel(app.SA_ConsumptionPatternPanel);
            app.AverageConnectedLoadinWattsLabel_2.Text = {'Average Connected Load '; '(in Watts)'};
            app.HoursLabel_2 = uilabel(app.SA_ConsumptionPatternPanel);
            app.HoursLabel_2.Text = 'Hours';
            app.DaysofAutonomyofthebatterystoragesystemDropDownLabel = uilabel(app.SA_ConsumptionPatternPanel);
            app.DaysofAutonomyofthebatterystoragesystemDropDownLabel.Text = 'Days of Autonomy of the battery storage system';
            app.DaysofAutonomyofthebatterystoragesystemDropDown = uidropdown(app.SA_ConsumptionPatternPanel);
            app.DaysofAutonomyofthebatterystoragesystemDropDown.Items = {'1', '2', '3'};
            app.DaysofAutonomyofthebatterystoragesystemDropDown.Value = '3';
            app.TypeofPVModuleDropDownLabel = uilabel(app.StandAloneTab);
            app.TypeofPVModuleDropDownLabel.Text = 'Type of PV Module';
            app.SA_TypeofPVModuleDropDown = uidropdown(app.StandAloneTab);
            app.SA_TypeofPVModuleDropDown.Items = {'Monocrystalline', 'Polycrystalline', 'Thin Film'};
            app.SA_TypeofPVModuleDropDown.Value = 'Monocrystalline';
            app.SA_ResultsPanel = uipanel(app.StandAloneTab);
            app.SA_ResultsPanel.Title = 'Results';
            app.BatteryAhEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.BatteryAhEditFieldLabel.Text = 'Battery Ah';
            app.BatteryAhEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.BatteryAhEditField.Editable = 'off';
            app.BatteryVoltageEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.BatteryVoltageEditFieldLabel.Text = 'Battery Voltage';
            app.BatteryVoltageEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.BatteryVoltageEditField.Editable = 'off';
            app.PVkWRatingEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.PVkWRatingEditFieldLabel.Text = 'PV kW Rating';
            app.PVkWRatingEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.PVkWRatingEditField.Editable = 'off';
            app.TotalInvestmentEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.TotalInvestmentEditFieldLabel.Text = 'Total Investment';
            app.SA_TotalInvestmentEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_TotalInvestmentEditField.ValueDisplayFormat = '%.1f';
            app.SA_TotalInvestmentEditField.Editable = 'off';
            app.SA_UIAxes = uiaxes(app.SA_ResultsPanel);
            title(app.SA_UIAxes, 'Average Daily Units')
            xlabel(app.SA_UIAxes, 'Month')
            ylabel(app.SA_UIAxes, 'kWh')
            app.ModuleCostEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.ModuleCostEditFieldLabel.Text = 'Module Cost';
            app.SA_ModuleCostEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_ModuleCostEditField.ValueDisplayFormat = '%.1f';
            app.SA_ModuleCostEditField.Editable = 'off';
            app.BatteryCostEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.BatteryCostEditFieldLabel.Text = 'Battery Cost';
            app.SA_BatteryCostEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_BatteryCostEditField.ValueDisplayFormat = '%.1f';
            app.SA_BatteryCostEditField.Editable = 'off';
            app.EPCCostEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.EPCCostEditFieldLabel.Text = 'EPC Cost';
            app.SA_EPCCostEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_EPCCostEditField.ValueDisplayFormat = '%.1f';
            app.SA_EPCCostEditField.Editable = 'off';
            app.TECCostEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.TECCostEditFieldLabel.Text = 'TEC Cost';
            app.SA_TECCostEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_TECCostEditField.ValueDisplayFormat = '%.1f';
            app.SA_TECCostEditField.Editable = 'off';
            app.AMCEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.AMCEditFieldLabel.Text = 'AMC';
            app.SA_AMCEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_AMCEditField.ValueDisplayFormat = '%.1f';
            app.SA_AMCEditField.Editable = 'off';
            app.LossofLoadEditFieldLabel = uilabel(app.SA_ResultsPanel);
            app.LossofLoadEditFieldLabel.Text = 'Loss of Load (%)';
            app.SA_LossofLoadEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_LossofLoadEditField.ValueDisplayFormat = '%.1f';
            app.SA_LossofLoadEditField.Editable = 'off';
            app.NoteLabel_2 = uilabel(app.SA_ResultsPanel);
            app.NoteLabel_2.Text = {'Note: The above estimate is inclusive of '; 'standard tax rates, and does not account '; 'for loan related interests and vendor margins.'};
            app.NoteLabel_3 = uilabel(app.SA_ResultsPanel);
            app.NoteLabel_3.Text = {'Abbreviations:'; 'EPC: Electrical Power Conditioning System '; 'AMC: Annual Maintenance Charges'; 'TEC: Testing Erection and Commissioning '};
            app.EconomicGrossEvaluationallfiguresinINRLabel = uilabel(app.SA_ResultsPanel);
            app.EconomicGrossEvaluationallfiguresinINRLabel.Text = 'Economic Gross Evaluation (all figures in INR)';
            app.SA_Error_Label = uilabel(app.SA_ResultsPanel);
            app.SA_Error_Label.Text = '';
            app.AreainsqmeterLabel = uilabel(app.SA_ResultsPanel);
            app.AreainsqmeterLabel.Text = 'Area in sq meter';
            app.SA_AreaEditField = uieditfield(app.SA_ResultsPanel, 'numeric');
            app.SA_AreaEditField.ValueDisplayFormat = '%.1f';
            app.SA_AreaEditField.Editable = 'off';
            app.SA_CalculateResultsButton = uibutton(app.StandAloneTab, 'push');
            app.SA_CalculateResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SA_CalculateResultsButtonPushed, true);
            app.SA_CalculateResultsButton.Text = 'Calculate Results';
            app.NoteLabel_8 = uilabel(app.StandAloneTab);
            app.NoteLabel_8.Text = 'Note: Enter Lumped Load Data';
            app.MonocrystallineButton = uibutton(app.StandAloneTab, 'push');
            app.MonocrystallineButton.Icon = 'Mono.png';
            app.MonocrystallineButton.Text = 'Monocrystalline';
            app.PolycrystallineButton = uibutton(app.StandAloneTab, 'push');
            app.PolycrystallineButton.Icon = 'Poly.png';
            app.PolycrystallineButton.Text = 'Polycrystalline';
            app.ThinFilmButton = uibutton(app.StandAloneTab, 'push');
            app.ThinFilmButton.Icon = 'Thin.png';
            app.ThinFilmButton.Text = 'Thin Film';
            app.GridTieTab = uitab(app.TabGroup);
            app.GridTieTab.Title = 'Grid Tie';
            app.DesignCriteriaButtonGroup = uibuttongroup(app.GridTieTab);
            app.DesignCriteriaButtonGroup.Title = 'Design Criteria';
            app.GT_AreainsqmButton = uiradiobutton(app.DesignCriteriaButtonGroup);
            app.GT_AreainsqmButton.Text = 'Area in sq.m';
            app.GT_AreainsqmButton.Value = true;
            app.GT_KWpratingButton = uiradiobutton(app.DesignCriteriaButtonGroup);
            app.GT_KWpratingButton.Text = 'KWp rating';
            app.GT_IP_EditField = uieditfield(app.DesignCriteriaButtonGroup, 'numeric');
            app.GT_IP_EditField.Limits = [0 Inf];
            app.UtilitySanctionedLoadinkWEditFieldLabel = uilabel(app.GridTieTab);
            app.UtilitySanctionedLoadinkWEditFieldLabel.Text = 'Utility Sanctioned Load (in kW)';
            app.UtilitySanctionedLoadinkWEditField = uieditfield(app.GridTieTab, 'numeric');
            app.UtilitySanctionedLoadinkWEditField.Limits = [0 Inf];
            app.Panel_2 = uipanel(app.GridTieTab);
            app.GT_UIAxes_1 = uiaxes(app.Panel_2);
            title(app.GT_UIAxes_1, 'Available Average Daily Energy')
            xlabel(app.GT_UIAxes_1, 'Month')
            ylabel(app.GT_UIAxes_1, 'kWh/sq meter.day')
            app.GT_UIAxes_2 = uiaxes(app.Panel_2);
            title(app.GT_UIAxes_2, 'Average Daily Units')
            xlabel(app.GT_UIAxes_2, 'Month')
            ylabel(app.GT_UIAxes_2, 'kWh')
            app.GT_EconomicGrossEvaluationPanel = uipanel(app.GridTieTab);
            app.GT_EconomicGrossEvaluationPanel.Title = 'Economic Gross Evaluation (all figures in INR)';
            app.NoteLabel_4 = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.NoteLabel_4.Text = {'Abbreviations:'; 'EPC: Electrical Power Conditioning System '; 'AMC: Annual Maintenance Charges'; 'AEC: Testing Erection and Commissioning '};
            app.TotalInvestmentEditField_2Label = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.TotalInvestmentEditField_2Label.Text = 'Total Investment';
            app.GT_TotalInvestmentEditField = uieditfield(app.GT_EconomicGrossEvaluationPanel, 'numeric');
            app.GT_TotalInvestmentEditField.ValueDisplayFormat = '%.1f';
            app.GT_TotalInvestmentEditField.Editable = 'off';
            app.ModuleCostEditField_2Label = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.ModuleCostEditField_2Label.Text = 'Module Cost';
            app.GT_ModuleCostEditField = uieditfield(app.GT_EconomicGrossEvaluationPanel, 'numeric');
            app.GT_ModuleCostEditField.ValueDisplayFormat = '%.1f';
            app.GT_ModuleCostEditField.Editable = 'off';
            app.EPCCostEditField_2Label = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.EPCCostEditField_2Label.Text = 'EPC Cost';
            app.GT_EPCCostEditField = uieditfield(app.GT_EconomicGrossEvaluationPanel, 'numeric');
            app.GT_EPCCostEditField.ValueDisplayFormat = '%.1f';
            app.GT_EPCCostEditField.Editable = 'off';
            app.TECCostEditField_2Label = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.TECCostEditField_2Label.Text = 'TEC Cost';
            app.GT_TECCostEditField = uieditfield(app.GT_EconomicGrossEvaluationPanel, 'numeric');
            app.GT_TECCostEditField.ValueDisplayFormat = '%.1f';
            app.GT_TECCostEditField.Editable = 'off';
            app.AMCEditField_2Label = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.AMCEditField_2Label.Text = 'AMC';
            app.GT_AMCEditField = uieditfield(app.GT_EconomicGrossEvaluationPanel, 'numeric');
            app.GT_AMCEditField.ValueDisplayFormat = '%.1f';
            app.GT_AMCEditField.Editable = 'off';
            app.NoteLabel_5 = uilabel(app.GT_EconomicGrossEvaluationPanel);
            app.NoteLabel_5.Text = {'Note: The above estimate is inclusive of '; 'standard tax rates, and does not account '; 'for loan related interests and vendor margins.'};
            app.GT_CalculateResultsButton = uibutton(app.GridTieTab, 'push');
            app.GT_CalculateResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GT_CalculateResultsButtonPushed, true);
            app.GT_CalculateResultsButton.Text = 'Calculate Results';
            app.LocationDropDown_2Label = uilabel(app.GridTieTab);
            app.LocationDropDown_2Label.Text = 'Location';
            app.GT_LocationDropDown = uidropdown(app.GridTieTab);
            app.GT_LocationDropDown.Items = {'New Delhi', 'Bangalore'};
            app.GT_LocationDropDown.Value = 'Bangalore';
            app.TypeofPVModuleDropDown_2Label = uilabel(app.GridTieTab);
            app.TypeofPVModuleDropDown_2Label.Text = 'Type of PV Module';
            app.GT_TypeofPVModuleDropDown = uidropdown(app.GridTieTab);
            app.GT_TypeofPVModuleDropDown.Items = {'Monocrystalline', 'Polycrystalline', 'Thin Film'};
            app.GT_TypeofPVModuleDropDown.Value = 'Monocrystalline';
            app.Button = uibutton(app.GridTieTab, 'push');
            app.Button.Icon = 'Grid Tie.png';
            app.Button.Text = '';
            app.InverterSpecsEditFieldLabel = uilabel(app.GridTieTab);
            app.InverterSpecsEditFieldLabel.Text = 'Inverter Specs';
            app.GT_InverterSpecsEditField = uieditfield(app.GridTieTab, 'text');
            app.KwpRatingEditFieldLabel = uilabel(app.GridTieTab);
            app.KwpRatingEditFieldLabel.Text = 'Kwp Rating';
            app.GT_KwpRatingEditField = uieditfield(app.GridTieTab, 'numeric');
            app.PowerPlantTab = uitab(app.TabGroup);
            app.PowerPlantTab.Title = 'Power Plant';
            app.DesignCriteriaButtonGroup_2 = uibuttongroup(app.PowerPlantTab);
            app.DesignCriteriaButtonGroup_2.Title = 'Design Criteria';
            app.PP_AreainsqmButton = uiradiobutton(app.DesignCriteriaButtonGroup_2);
            app.PP_AreainsqmButton.Text = 'Area in sq.m';
            app.PP_AreainsqmButton.Value = true;
            app.PP_CostButton = uiradiobutton(app.DesignCriteriaButtonGroup_2);
            app.PP_CostButton.Text = 'Cost in INR';
            app.PP_IP_EditField = uieditfield(app.DesignCriteriaButtonGroup_2, 'numeric');
            app.PP_IP_EditField.Limits = [0 Inf];
            app.Panel_3 = uipanel(app.PowerPlantTab);
            app.PP_UIAxes_1 = uiaxes(app.Panel_3);
            title(app.PP_UIAxes_1, 'Available Average Daily Energy')
            xlabel(app.PP_UIAxes_1, 'Month')
            ylabel(app.PP_UIAxes_1, 'kWh/sq meter.day')
            app.PP_UIAxes_2 = uiaxes(app.Panel_3);
            title(app.PP_UIAxes_2, 'Average Daily Units')
            xlabel(app.PP_UIAxes_2, 'Month')
            ylabel(app.PP_UIAxes_2, 'kWh')
            app.PP_EconomicGrossEvaluationPanel = uipanel(app.PowerPlantTab);
            app.PP_EconomicGrossEvaluationPanel.Title = 'Economic Gross Evaluation (all figures in INR)';
            app.NoteLabel_6 = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.NoteLabel_6.Text = {'Abbreviations:'; 'EPC: Electrical Power Conditioning System '; 'AMC: Annual Maintenance Charges'; 'TEC:Testing Erection and Commissioning '};
            app.TotalInvestmentEditField_3Label = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.TotalInvestmentEditField_3Label.Text = 'Total Investment';
            app.PP_TotalInvestmentEditField = uieditfield(app.PP_EconomicGrossEvaluationPanel, 'numeric');
            app.PP_TotalInvestmentEditField.ValueDisplayFormat = '%.1f';
            app.PP_TotalInvestmentEditField.Editable = 'off';
            app.ModuleCostEditField_3Label = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.ModuleCostEditField_3Label.Text = 'Module Cost';
            app.PP_ModuleCostEditField = uieditfield(app.PP_EconomicGrossEvaluationPanel, 'numeric');
            app.PP_ModuleCostEditField.ValueDisplayFormat = '%.1f';
            app.PP_ModuleCostEditField.Editable = 'off';
            app.EPCCostEditField_3Label = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.EPCCostEditField_3Label.Text = 'EPC Cost';
            app.PP_EPCCostEditField = uieditfield(app.PP_EconomicGrossEvaluationPanel, 'numeric');
            app.PP_EPCCostEditField.ValueDisplayFormat = '%.1f';
            app.PP_EPCCostEditField.Editable = 'off';
            app.TECCostEditField_3Label = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.TECCostEditField_3Label.Text = 'TEC Cost';
            app.PP_TECCostEditField = uieditfield(app.PP_EconomicGrossEvaluationPanel, 'numeric');
            app.PP_TECCostEditField.ValueDisplayFormat = '%.1f';
            app.PP_TECCostEditField.Editable = 'off';
            app.AMCEditField_3Label = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.AMCEditField_3Label.Text = 'AMC';
            app.PP_AMCEditField = uieditfield(app.PP_EconomicGrossEvaluationPanel, 'numeric');
            app.PP_AMCEditField.ValueDisplayFormat = '%.1f';
            app.PP_AMCEditField.Editable = 'off';
            app.NoteLabel_7 = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.NoteLabel_7.Text = {'Note: The above estimate is inclusive of '; 'standard tax rates, and does not account '; 'for loan related interests and vendor margins.'};
            app.EBChargesEditFieldLabel = uilabel(app.PP_EconomicGrossEvaluationPanel);
            app.EBChargesEditFieldLabel.Text = 'EB Charges';
            app.PP_EBChargesEditField = uieditfield(app.PP_EconomicGrossEvaluationPanel, 'numeric');
            app.PP_EBChargesEditField.ValueDisplayFormat = '%.1f';
            app.PP_PlantConfigurationPanel = uipanel(app.PowerPlantTab);
            app.PP_PlantConfigurationPanel.Title = 'Plant Configuration';
            app.NumberofStringsEditFieldLabel = uilabel(app.PP_PlantConfigurationPanel);
            app.NumberofStringsEditFieldLabel.Text = 'Number of Strings';
            app.PP_NumberofStringsEditField = uieditfield(app.PP_PlantConfigurationPanel, 'numeric');
            app.PP_NumberofStringsEditField.ValueDisplayFormat = '%.0f';
            app.PP_NumberofStringsEditField.Editable = 'off';
            app.NumberofSubStringsEditFieldLabel = uilabel(app.PP_PlantConfigurationPanel);
            app.NumberofSubStringsEditFieldLabel.Text = 'Number of Sub-Strings';
            app.PP_NumberofSubStringsEditField = uieditfield(app.PP_PlantConfigurationPanel, 'numeric');
            app.PP_NumberofSubStringsEditField.Editable = 'off';
            app.NumberofInvertersEditFieldLabel = uilabel(app.PP_PlantConfigurationPanel);
            app.NumberofInvertersEditFieldLabel.Text = 'Number of Inverters';
            app.PP_NumberofInvertersEditField = uieditfield(app.PP_PlantConfigurationPanel, 'numeric');
            app.InverterSpecsEditField_2Label = uilabel(app.PP_PlantConfigurationPanel);
            app.InverterSpecsEditField_2Label.Text = 'Inverter Specs';
            app.PP_InverterSpecsEditField = uieditfield(app.PP_PlantConfigurationPanel, 'numeric');
            app.MWLabel = uilabel(app.PP_PlantConfigurationPanel);
            app.MWLabel.Text = 'MW';
            app.PlantMWCapacityEditFieldLabel = uilabel(app.PP_PlantConfigurationPanel);
            app.PlantMWCapacityEditFieldLabel.Text = 'Plant MW Capacity';
            app.PP_PlantMWCapacityEditField = uieditfield(app.PP_PlantConfigurationPanel, 'numeric');
            app.PlantAreainsqmetersEditFieldLabel = uilabel(app.PP_PlantConfigurationPanel);
            app.PlantAreainsqmetersEditFieldLabel.Text = 'Plant Area in sq meters';
            app.PlantAreainsqmetersEditField = uieditfield(app.PP_PlantConfigurationPanel, 'numeric');
            app.PP_CalculateResultsButton = uibutton(app.PowerPlantTab, 'push');
            app.PP_CalculateResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PP_CalculateResultsButtonPushed, true);
            app.PP_CalculateResultsButton.Text = 'Calculate Results';
            app.LocationDropDown_3Label = uilabel(app.PowerPlantTab);
            app.LocationDropDown_3Label.Text = 'Location';
            app.PP_LocationDropDown = uidropdown(app.PowerPlantTab);
            app.PP_LocationDropDown.Items = {'New Delhi', 'Bangalore'};
            app.PP_LocationDropDown.Value = 'Bangalore';
            app.TypeofPVModuleDropDown_3Label = uilabel(app.PowerPlantTab);
            app.TypeofPVModuleDropDown_3Label.Text = 'Type of PV Module';
            app.PP_TypeofPVModuleDropDown = uidropdown(app.PowerPlantTab);
            app.PP_TypeofPVModuleDropDown.Items = {'Monocrystalline', 'Polycrystalline', 'Thin Film'};
            app.PP_TypeofPVModuleDropDown.Value = 'Monocrystalline';
            app.InverterConfigurationLabel = uilabel(app.PowerPlantTab);
            app.InverterConfigurationLabel.Text = 'Inverter Configuration';
            app.PP_RedundancyDropDown = uidropdown(app.PowerPlantTab);
            app.PP_RedundancyDropDown.Items = {'Level 0 - Centralised Inverter', 'Level 1 - String Inverter', 'Level 2 - String Inverter', 'Level 3 - String Inverter'};
            app.PP_RedundancyDropDown.Value = 'Level 0 - Centralised Inverter';
            app.Label2 = uilabel(app.PowerPlantTab);
            app.Label2.Text = {'Higher the level, easier is the '; 'electrical management and higher the safety.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Design_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PVSystemDesignUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PVSystemDesignUIFigure)
        end
    end
end