classdef Fault_Diagnosis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiagnosticGUIUIFigure           matlab.ui.Figure
        BatteryStateofChargeGaugeLabel  matlab.ui.control.Label
        BatteryStateofChargeGauge       matlab.ui.control.Gauge
        BatteryVoltageGaugeLabel        matlab.ui.control.Label
        BatteryVoltageGauge             matlab.ui.control.SemicircularGauge
        PanelVoltageGaugeLabel          matlab.ui.control.Label
        PanelVoltageGauge               matlab.ui.control.SemicircularGauge
        UIAxes                          matlab.ui.control.UIAxes
        DiagnosticReportEditField       matlab.ui.control.EditField
        MONITORINGANDFAULTDIAGNOSTICTOOLFORSTANDALONEPVSYSTEMLabel  matlab.ui.control.Label
        RunDiagnosisButton              matlab.ui.control.Button
        LoadPowerGaugeLabel             matlab.ui.control.Label
        LoadPowerGauge                  matlab.ui.control.Gauge
        WLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        SymptomEditFIeld                matlab.ui.control.EditField
        EquipmentEditField              matlab.ui.control.EditField
        DateTimeEditField               matlab.ui.control.EditField
        DiagnosticReportLabel           matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: RunDiagnosisButton
        function RunDiagnosisButtonPushed(app, event)
            flag = 0;
            A = dlmread('jb.txt');
            vpanel = A(:,1);
            vdcconverter = A(:,2);
            idcconverter = A(:,3);
            socbat = A(:,4);
            ibattery = A(:,5);
            vac = A(:,6);
            iac = A(:,7);
            vbattery = A(:,8);
            
            
            k = size(vac);
            
            vpv = mean(vpanel);
            vdcdc = mean(vdcconverter);
            idcdc = mean(idcconverter);
            soc = mean(socbat);
            ibat = mean(ibattery);
            vbat = mean(vbattery);
            vrms = sqrt(mean(vac .* vac));
            vdc = mean(vac);
            irms = sqrt(mean(iac .* iac));
            
            
            Pload = vrms*irms;
            
            app.LoadPowerGauge.Value = Pload;
            app.BatteryStateofChargeGauge.Value = soc;
            app.BatteryVoltageGauge.Value = vbat;
            app.PanelVoltageGauge.Value = vpv;
            
            vplot = vac((k-100000):k);
            p = plot(app.UIAxes, vplot,'r-');
            p.LineWidth = 2;
            
            
            t = datetime('now');
            dateString = datestr(t);
            app.DateTimeEditField.Value = dateString;


            % Diagnostic Report
            
            if( (abs(vdc)< 0.1) && ((vdcdc-1) <= vrms) && (vrms <= (vdcdc+1)) && (soc>10) && (idcdc > 0) && (vpv > 36) && (abs(ibat)>0))
                app.EquipmentEditField.Value = "No Faulty Equipment";
                app.SymptomEditFIeld.Value = "System Parameters - Normal";
                app.DiagnosticReportEditField.Value = "System Healthy";
                flag = 0;
            elseif((abs(vdc)> 1) &&( (vrms>vdcdc+1) || (vrms<vdcdc-1)) && (vpv >36))
                app.EquipmentEditField.Value = "Fault in the Inverter";
                app.SymptomEditFIeld.Value = "Loss of half AC Cycle";
                app.DiagnosticReportEditField.Value = "Fault Label: F1 | One IGBT Open";
                flag = 1;  
            elseif((abs(vdc) < 1e-3) && (vrms < 0.5) && (abs(vdcdc)>11))
                app.EquipmentEditField.Value = "Fault in the Inverter";
                app.SymptomEditFIeld.Value = "No inverter Output";
                app.DiagnosticReportEditField.Value = "Fault Label: F2 | Two IGBT Open";
                flag = 1;  
            elseif((abs(vdc)<0.5) && (vrms >1) && (vrms < 10))
                app.EquipmentEditField.Value = "Fault in the Inverter";
                app.SymptomEditFIeld.Value = "Increase in Load Harmonics";
                app.DiagnosticReportEditField.Value = "Fault Label: F3 | IGBT Misfiring";
                flag = 1; 
            elseif((abs(vdcdc) < 1) && (vrms == 0) && (vdc == 0))
                app.EquipmentEditField.Value = "Fault in the Inverter OR Battery";
                app.SymptomEditFIeld.Value = "No Load Output and Severe Drop in DC Bus Voltage";
                app.DiagnosticReportEditField.Value = "Fault Label: F4 | IGBTs (Same Leg) OR Battery Terminal, Shorted";
                flag = 1;
            elseif((vdcdc < 11) && (vpv < 36) && (vpv > 10) && (abs(vdc) > 1))
                app.EquipmentEditField.Value = "Fault in the Inverter";
                app.SymptomEditFIeld.Value = "Loss of half AC Cycle and Reduction in DC Bus Voltage";
                app.DiagnosticReportEditField.Value = "Fault Label: F5 | Short Circuit of IGBT(s)";
                flag = 1;  
            elseif(abs(ibat) < 0.1)
                app.EquipmentEditField.Value = "Fault in the Battery";
                app.SymptomEditFIeld.Value = "Battery Current is Zero";
                app.DiagnosticReportEditField.Value = "Fault Label: F6 | Battery Terminal Disconnect";
                flag = 1;  
            elseif((abs(vpv) >36) && (abs(idcdc) <0.1))
                app.EquipmentEditField.Value = "Fault in the DC-DC Converter";
                app.SymptomEditFIeld.Value = "Current Injected from Converter to DC Bus is Zero";
                app.DiagnosticReportEditField.Value = "Fault Label: F7 | Failure in Converter Switch Firing";
                flag = 1;   
            elseif((abs(vpv) < 1) && (abs(idcdc) <0.1))
                app.EquipmentEditField.Value = "Fault in the PV Panel";
                app.SymptomEditFIeld.Value = "No PV Panel Output";
                app.DiagnosticReportEditField.Value = "Fault Label: F8 | Panel Terminal Disconnect OR Defective Panel";
                flag = 1;
            elseif((abs(vdcdc) < 2) && (0 <= vdc) && (vdc <1) && (0 <= vrms) && (vrms <1))
                app.EquipmentEditField.Value = "Fault in the Inverter";
                app.SymptomEditFIeld.Value = "Reduction in DC Bus Voltage and Load RMS Voltage";
                app.DiagnosticReportEditField.Value = "Fault Label: F9 | IGBT Bridge Fault";
                flag = 1;
            else
                app.DiagnosticReportEditField.Value = "Unknown";
            end
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiagnosticGUIUIFigure = uifigure;
            app.DiagnosticGUIUIFigure.Name = 'Diagnostic GUI';
            app.BatteryStateofChargeGaugeLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.BatteryStateofChargeGaugeLabel.Text = 'Battery State of Charge';
            app.BatteryStateofChargeGauge = uigauge(app.DiagnosticGUIUIFigure, 'circular');
            app.BatteryVoltageGaugeLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.BatteryVoltageGaugeLabel.Text = 'Battery Voltage';
            app.BatteryVoltageGauge = uigauge(app.DiagnosticGUIUIFigure, 'semicircular');
            app.BatteryVoltageGauge.Limits = [0 15];
            app.BatteryVoltageGauge.MajorTicks = [0 3 6 9 12 15];
            app.PanelVoltageGaugeLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.PanelVoltageGaugeLabel.Text = 'Panel Voltage';
            app.PanelVoltageGauge = uigauge(app.DiagnosticGUIUIFigure, 'semicircular');
            app.PanelVoltageGauge.Limits = [0 40];
            app.UIAxes = uiaxes(app.DiagnosticGUIUIFigure);
            title(app.UIAxes, 'Inverter Output')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Volts')
            app.DiagnosticReportEditField = uieditfield(app.DiagnosticGUIUIFigure, 'text');
            app.MONITORINGANDFAULTDIAGNOSTICTOOLFORSTANDALONEPVSYSTEMLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.MONITORINGANDFAULTDIAGNOSTICTOOLFORSTANDALONEPVSYSTEMLabel.Text = 'MONITORING AND FAULT DIAGNOSTIC TOOL FOR STAND ALONE PV SYSTEM';
            app.RunDiagnosisButton = uibutton(app.DiagnosticGUIUIFigure, 'push');
            app.RunDiagnosisButton.ButtonPushedFcn = createCallbackFcn(app, @RunDiagnosisButtonPushed, true);
            app.RunDiagnosisButton.Text = 'Run Diagnosis';
            app.LoadPowerGaugeLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.LoadPowerGaugeLabel.Text = 'Load Power';
            app.LoadPowerGauge = uigauge(app.DiagnosticGUIUIFigure, 'circular');
            app.WLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.WLabel.Text = 'W';
            app.Label = uilabel(app.DiagnosticGUIUIFigure);
            app.Label.Text = '%';
            app.VLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.DiagnosticGUIUIFigure);
            app.VLabel_2.Text = 'V';
            app.SymptomEditFIeld = uieditfield(app.DiagnosticGUIUIFigure, 'text');
            app.EquipmentEditField = uieditfield(app.DiagnosticGUIUIFigure, 'text');
            app.DateTimeEditField = uieditfield(app.DiagnosticGUIUIFigure, 'text');
            app.DiagnosticReportLabel = uilabel(app.DiagnosticGUIUIFigure);
            app.DiagnosticReportLabel.Text = 'Diagnostic Report';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fault_Diagnosis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiagnosticGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiagnosticGUIUIFigure)
        end
    end
end